classdef matcher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DesignNetworkButton            matlab.ui.control.Button
        StubPropertiesPanel            matlab.ui.container.Panel
        sR0                            matlab.ui.control.NumericEditField
        CharachteristicImpedanceEditField_2Label  matlab.ui.control.Label
        TransmissionLinePropertiesPanel  matlab.ui.container.Panel
        tlR0                           matlab.ui.control.NumericEditField
        CharachteristicImpedanceLabel  matlab.ui.control.Label
        MatchingNetworkTypeDropDownLabel  matlab.ui.control.Label
        MatchingNetworkTypeDropDown    matlab.ui.control.DropDown
        LoadPropertiesPanel            matlab.ui.container.Panel
        ZrealjImagLabel                matlab.ui.control.Label
        zlImag                         matlab.ui.control.NumericEditField
        ImpedanceImaginaryPartEditFieldLabel  matlab.ui.control.Label
        zlReal                         matlab.ui.control.NumericEditField
        ImpedanceRealPartEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Update the label value to match the user input
            if app.zlImag.Value < 0
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + "-j" + num2str(abs(app.zlImag.Value)) + " Ω";
            elseif app.zlImag.Value == 0
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + " Ω";

            else
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + "+j" + num2str(app.zlImag.Value) + " Ω" ;
            end
            if app.zlReal.Value == 0 && app.zlImag.Value ~= 0
                if app.zlImag.Value == 0
                    app.ZrealjImagLabel.Text = "Z = 0 Ω" ;
                elseif app.zlImag.Value < 0
                    app.ZrealjImagLabel.Text = "Z = -j" + num2str(abs(app.zlImag.Value)) + " Ω" ;
                else
                    app.ZrealjImagLabel.Text = "Z = j" + num2str(app.zlImag.Value) + " Ω" ;
                end
            end
        end

        % Value changed function: zlReal
        function zlRealValueChanged(app, event)
            % Update the label value to match the user input
            if app.zlImag.Value < 0
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + "-j" + num2str(abs(app.zlImag.Value)) + " Ω";
            elseif app.zlImag.Value == 0
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + " Ω";

            else
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + "+j" + num2str(app.zlImag.Value) + " Ω" ;
            end
            if app.zlReal.Value == 0 && app.zlImag.Value ~= 0
                if app.zlImag.Value == 0
                    app.ZrealjImagLabel.Text = "Z = 0 Ω" ;
                elseif app.zlImag.Value < 0
                    app.ZrealjImagLabel.Text = "Z = -j" + num2str(abs(app.zlImag.Value)) + " Ω" ;
                else
                    app.ZrealjImagLabel.Text = "Z = j" + num2str(app.zlImag.Value) + " Ω" ;
                end
            end
        end

        % Value changed function: zlImag
        function zlImagValueChanged(app, event)
            % Update the label value to match the user input
            if app.zlImag.Value < 0
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + "-j" + num2str(abs(app.zlImag.Value)) + " Ω";
            elseif app.zlImag.Value == 0
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + " Ω";

            else
                app.ZrealjImagLabel.Text = "Z = " +num2str(app.zlReal.Value) + "+j" + num2str(app.zlImag.Value) + " Ω" ;
            end
            if app.zlReal.Value == 0 && app.zlImag.Value ~= 0
                if app.zlImag.Value == 0
                    app.ZrealjImagLabel.Text = "Z = 0 Ω" ;
                elseif app.zlImag.Value < 0
                    app.ZrealjImagLabel.Text = "Z = -j" + num2str(abs(app.zlImag.Value)) + " Ω" ;
                else
                    app.ZrealjImagLabel.Text = "Z = j" + num2str(app.zlImag.Value) + " Ω" ;
                end
            end
        end

        % Button pushed function: DesignNetworkButton
        function DesignNetworkButtonPushed(app, event)
            % Design the matching network and view the result in a pop up.
            matchType = app.MatchingNetworkTypeDropDown.Value;
            zLoad = app.ZrealjImagLabel.Text;
            tLineR0 = app.tlR0;
            stubR0 = app.sR0;

            displayMsg = "Network Type: " + matchType + newline + "Load Impedance " + zLoad(3:end) + newline + "Transmission Line Rₒ = "+ tLineR0.Value  + " Ω" + newline + "Stub Rₒ = " + stubR0.Value + " Ω" ;

            uialert(app.UIFigure, displayMsg,'Matching Networks Parameters', 'Icon', 'info')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadPropertiesPanel = uipanel(app.UIFigure);
            app.LoadPropertiesPanel.Title = 'Load Properties';
            app.ImpedanceRealPartEditFieldLabel = uilabel(app.LoadPropertiesPanel);
            app.ImpedanceRealPartEditFieldLabel.Text = 'Impedance Real Part';
            app.zlReal = uieditfield(app.LoadPropertiesPanel, 'numeric');
            app.zlReal.ValueChangedFcn = createCallbackFcn(app, @zlRealValueChanged, true);
            app.ImpedanceImaginaryPartEditFieldLabel = uilabel(app.LoadPropertiesPanel);
            app.ImpedanceImaginaryPartEditFieldLabel.Text = 'Impedance Imaginary Part';
            app.zlImag = uieditfield(app.LoadPropertiesPanel, 'numeric');
            app.zlImag.ValueChangedFcn = createCallbackFcn(app, @zlImagValueChanged, true);
            app.ZrealjImagLabel = uilabel(app.LoadPropertiesPanel);
            app.ZrealjImagLabel.Text = 'Z =  real+jImag';
            app.MatchingNetworkTypeDropDown = uidropdown(app.UIFigure);
            app.MatchingNetworkTypeDropDown.Items = {'Single Stub - Series', 'Single Stub - Parallel', 'Double Stub - Parallel'};
            app.MatchingNetworkTypeDropDown.Value = 'Single Stub - Series';
            app.MatchingNetworkTypeDropDownLabel = uilabel(app.UIFigure);
            app.MatchingNetworkTypeDropDownLabel.Text = 'Matching Network Type';
            app.TransmissionLinePropertiesPanel = uipanel(app.UIFigure);
            app.TransmissionLinePropertiesPanel.Title = 'Transmission Line Properties';
            app.CharachteristicImpedanceLabel = uilabel(app.TransmissionLinePropertiesPanel);
            app.CharachteristicImpedanceLabel.Text = 'Charachteristic Impedance (Ω)';
            app.tlR0 = uieditfield(app.TransmissionLinePropertiesPanel, 'numeric');
            app.StubPropertiesPanel = uipanel(app.UIFigure);
            app.StubPropertiesPanel.Title = 'Stub Properties';
            app.CharachteristicImpedanceEditField_2Label = uilabel(app.StubPropertiesPanel);
            app.CharachteristicImpedanceEditField_2Label.Text = 'Charachteristic Impedance (Ω)';
            app.sR0 = uieditfield(app.StubPropertiesPanel, 'numeric');
            app.DesignNetworkButton = uibutton(app.UIFigure, 'push');
            app.DesignNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @DesignNetworkButtonPushed, true);
            app.DesignNetworkButton.Text = 'Design Network';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matcher

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end